classdef Calcium_Imaging_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        ExportAs                        matlab.ui.control.ListBox
        MessageLabel                    matlab.ui.control.Label
        Startbyloading_Label            matlab.ui.control.Label
        LoadYourFileButton              matlab.ui.control.Button
        Hereyouwillsee_Label            matlab.ui.control.Label
        elipsis_Undefined               matlab.ui.control.Button
        elipsis_Plateau                 matlab.ui.control.Button
        elipsis_Low                     matlab.ui.control.Button
        ExportAs_Oscilatory             matlab.ui.control.ListBox
        ExportAs_Low                    matlab.ui.control.ListBox
        ExportAs_Plateau                matlab.ui.control.ListBox
        ExportAs_Undefined              matlab.ui.control.ListBox
        elipsis_Oscilatory              matlab.ui.control.Button
        elipsis                         matlab.ui.control.Button
        Pleaseupload_Label              matlab.ui.control.Label
        MessageLabel_8                  matlab.ui.control.Label
        ParameterPanel                  matlab.ui.container.Panel
        FrameRateEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        TimeToBaselineEditField         matlab.ui.control.NumericEditField
        TimeToBaselineEditFieldLabel    matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        ApplyChngesButton               matlab.ui.control.Button
        O_MinimalDistance               matlab.ui.control.Spinner
        MinimalDistancesecSpinnerLabel  matlab.ui.control.Label
        O_MinimalProminence             matlab.ui.control.Spinner
        MinimalProminenceLabel          matlab.ui.control.Label
        O_MaximalDuration               matlab.ui.control.Spinner
        MaximalDurationsecSpinnerLabel  matlab.ui.control.Label
        O_MinimalDuration               matlab.ui.control.Spinner
        MinimalDurationsecLabel         matlab.ui.control.Label
        SmoothingFactorEditField        matlab.ui.control.NumericEditField
        SmoothingFactorEditFieldLabel   matlab.ui.control.Label
        TrimmingEditField               matlab.ui.control.NumericEditField
        TrimmingEditFieldLabel          matlab.ui.control.Label
        TimeToAnalyzeEditField          matlab.ui.control.NumericEditField
        TimeToAnalyzeEditFieldLabel     matlab.ui.control.Label
        GeneralParametersLabel          matlab.ui.control.Label
        OligodendrocytesParametersLabel  matlab.ui.control.Label
        DefaultiveCheckBox              matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        AxesOscillatory                 matlab.ui.control.UIAxes
        AxesLowActivity                 matlab.ui.control.UIAxes
        AxesPlateauResponse             matlab.ui.control.UIAxes
        AxesUndefined                   matlab.ui.control.UIAxes
        FeatureExtractionTab            matlab.ui.container.Tab
        MessageLabel_3                  matlab.ui.control.Label
        MessageLabel_2                  matlab.ui.control.Label
        Image_6                         matlab.ui.control.Image
        ChoosematfilesButton            matlab.ui.control.Button
        SaveFeaturesButton              matlab.ui.control.Button
        conditionButtonGroup            matlab.ui.container.ButtonGroup
        ControlButton                   matlab.ui.control.RadioButton
        MutantButton                    matlab.ui.control.RadioButton
        FeatureExtractionLabel          matlab.ui.control.Label
        SanityCheckTab                  matlab.ui.container.Tab
        MessageLabel_7                  matlab.ui.control.Label
        MessageLabel_6                  matlab.ui.control.Label
        ChooseCellNumberEditField_2     matlab.ui.control.NumericEditField
        ChooseCellNumberEditFieldLabel_2  matlab.ui.control.Label
        SaveplotButton                  matlab.ui.control.Button
        ChoosematfileButton             matlab.ui.control.Button
        Image_7                         matlab.ui.control.Image
        SanityCheckLabel                matlab.ui.control.Label
        sanityAxes                      matlab.ui.control.UIAxes
        landingScreen                   matlab.ui.container.Panel
        PleaseselectwhereyoudliketobeginLabel  matlab.ui.control.Label
        BatchAnalysisButton             matlab.ui.control.Button
        SingleFileButton                matlab.ui.control.Button
        Image_8                         matlab.ui.control.Image
        WelcometotheOPCCalciumAnalysisAPPLabel  matlab.ui.control.Label
    end

    % Properties to store data and UI components
    properties (Access = public)
        file
        ExcelData % Property to store CSV data
        TimeVec % Property to store time vector
        % Variables to store signal data
        Oscillatory_sig
        LowPlateus_sig
        Plateau_sig
        Undefined_sig
        num_cells
        SelectedLine % Property to store the currently selected line
        OriginalAxes % Property to store the original axes of the selected line
        OriginalXData % Property to store the original X data of the selected line
        OriginalYData % Property to store the original Y data of the selected line
        condition                % Selected condition
        osci_mut                 % Structure for mutant condition
        osci_ctr                 % Structure for control condition
        LastExportOscilatoryValue = ''  % To track last selected value
    end
    
         % Callbacks that handle component events
         methods (Access = private)
%% preproccesing
% creat_osci_struct function
        function [osci_struct, TotalCell_final] = create_osci_struct(CurrMat, osci_struct, time_vec, N_TotalROI, genotype, k, i)
            for j = 1:size(CurrMat, 2)
                osci_struct(N_TotalROI).ID = sprintf('%s%d', genotype{k}, i); %mouse ID
                osci_struct(N_TotalROI).Genotype = sprintf('ROI_#%d', N_TotalROI); %ROI number
                osci_struct(N_TotalROI).Signal = CurrMat(:, j)'; %cell calcium trace signal
                sig = osci_struct(N_TotalROI).Signal;
                time = round(time_vec);
                sprintf('ROI number: %d', N_TotalROI)
                
                if size(CurrMat, 1) < 600 %find glutamate administration index
                    glut_inj_idx = find(time == 60); 
                else
                    glut_inj_idx = find(time == 120); 
                end
                
                osci_struct(N_TotalROI).Time_vec = time_vec; %insert time_vec to osci_struct
                osci_struct(N_TotalROI).GlutInj_idx = glut_inj_idx; %insert glutamate administration index to osci_struct
                
                % Call the external analys_oscillations function
                [n_spike, f_spike, d_spike, p_spike, max_sig, p_spike_norm, amp_spike, mean_amp_spike, areaAmp_base, areaAmp_prom, onset_time, onset_idx, offset_time, offset_idx, rise_time30, decay_time30, timeLat, areaLat_prom, areaLat_base, duration, area_prom, area_base, inter_peak_intervals] = analys_oscillations(sig, time_vec, glut_inj_idx, 0);
                
                osci_struct(N_TotalROI).N_peaks = n_spike;
                osci_struct(N_TotalROI).N_peaks_minut = f_spike;
                osci_struct(N_TotalROI).Max_intensity = max_sig;
                osci_struct(N_TotalROI).Prominence = p_spike;
                osci_struct(N_TotalROI).NormProminence = p_spike_norm;
                osci_struct(N_TotalROI).NormProminence_mean_AllPeaks = mean(p_spike_norm); %mean prominence (all spikes)
                osci_struct(N_TotalROI).Amplitude = amp_spike;
                osci_struct(N_TotalROI).Amplitude_mean_AllPeaks = mean(amp_spike); %mean amplitude (all spikes)
                osci_struct(N_TotalROI).MeanAmp = mean_amp_spike; %amplotude in 3 time frames around the maxima
                osci_struct(N_TotalROI).AreaAmp_0Base = areaAmp_base;
                osci_struct(N_TotalROI).AreaAmp_30Prom = areaAmp_prom;
                osci_struct(N_TotalROI).Latency_peak = timeLat;
                osci_struct(N_TotalROI).AreaLat_30Prom = areaLat_prom;
                osci_struct(N_TotalROI).AreaLat_0Base = areaLat_base;
                osci_struct(N_TotalROI).Interval__between_peaks = d_spike;
                osci_struct(N_TotalROI).Duration_30Prom = duration; %spike duration (area base)
                osci_struct(N_TotalROI).RiseTime = rise_time30; %rise time of peaks 30% to 90% prominence
                osci_struct(N_TotalROI).DecayTime = decay_time30; %decay time of peaks 90% to 30% prominence
                osci_struct(N_TotalROI).OnsetTime = onset_time;
                osci_struct(N_TotalROI).OnsetIdx = onset_idx;
                osci_struct(N_TotalROI).OffsetTime = offset_time;
                osci_struct(N_TotalROI).OffsetIdx = offset_idx;
                osci_struct(N_TotalROI).InterPeaks_intervals = inter_peak_intervals;
                osci_struct(N_TotalROI).Area_30Prom = area_prom; %area of all spikes
                osci_struct(N_TotalROI).Area_0Base = area_base; %area of all spikes
                osci_struct(N_TotalROI).Area_AllPeaks_30Prom = sum(area_prom); %total area of all spikes (sum all areas)       
                osci_struct(N_TotalROI).Area_AllPeaks_30Base = sum(area_base); %total area of all spikes (sum all areas)
        
                
                fields = {'Prom_%d_peak', 'NormProm_%d_peak', 'Amp_%d_peak', 'MeanAmp_%d_peak', 'AreaAmp_0base_%d_peak', 'AreaAmp_30prom_%d_peak', 'Lat_%d_peak', 'AreaLat_30prom_%d_peak', 'Inter_peaks_%d', 'Duration_30Prom_%d', 'RiseTime_%d', 'DecayTime_%d', 'OnsetTime_%d', 'OffsetTime_%d', 'Inter_%d_Off_On', 'Area_30Prom_%d', 'Area_0Base_%d'};
                dataset = {p_spike, p_spike_norm, amp_spike, mean_amp_spike, areaAmp_base, areaAmp_prom, timeLat, areaLat_prom, d_spike, duration, rise_time30, decay_time30, onset_time, offset_time, inter_peak_intervals, area_prom, area_base};
                for f = 1:length(fields)
                    for j = 1:n_spike
                        field = sprintf(fields{f}, j);
                        data = dataset{f};
                        if length(data) >= j
                            osci_struct(N_TotalROI).(field) = data(j);
                        else
                            osci_struct(N_TotalROI).(field) = [];
                        end
                    end
                end
                
                N_TotalROI = N_TotalROI + 1;
            end
            TotalCell_final = N_TotalROI;
        end

            % Preprocess and classify the signals, then plot the results
            function preprocessAndPlot(app, data, time_vec, admn_time, trim, dt)
            % Adjust administration time based on trim and time step
            admn_time = admn_time + dt - trim;
            admn_idx = round(admn_time / dt); 
            n_cells = size(data, 2);
            
            % Initialize variables for different signal types
            app.num_cells = 0;
            num_ocillatory_sig = 0;
            num_LowPlateus_sig = 0;
            num_Plateau_sig = 0;
            baseline_mat = [];
            baseline_raw_mat = [];
            baseline_corr_mat = [];
            baseline_perc_mat = [];
            baseline_corrected_data = [];
            smooth_cell_data = [];
            app.Oscillatory_sig = [];
            app.LowPlateus_sig = [];
            app.Plateau_sig = [];
            app.Undefined_sig = [];


            for cell_num = 1:n_cells
                % Trimming first 'trim' seconds of ROIs traces
                if trim > length(data)
                    disp('Trim value exceeds data length.');
                    return;
                end
                cell_RawData = data(trim:end, cell_num); 
    
                % Baseline correction  
                baseline_sig = cell_RawData(1:admn_idx); % signal until drug administration
                baseline_raw_mat(:, end + 1) = baseline_sig; % matrix of all baseline signals
                baseline_time = time_vec(1:admn_idx);
                baseline = mean(baseline_sig); % baseline = F0
                baseline_corr_mat(:, end + 1) = baseline_sig - baseline;
                baseline_perc_mat(:, end + 1) = (baseline_sig - baseline) / baseline;
                cell_data = (cell_RawData - baseline) / baseline; % ΔF/F0
                active_cell_data = cell_data(admn_idx:end, :); % signal after drug administration expressed as (delta F)/F0
                baseline_corrected_data(:, end + 1) = cell_data; % matrix of all baseline corrected signals expressed as (delta F)/F0
    
                % Smooth the data
                smooth_cell_data(:, end + 1) = smooth(cell_data, app.SmoothingFactorEditField.Value);
                smooth_cell_data = smooth(cell_data, app.SmoothingFactorEditField.Value);
                active_smooth_cell_data = smooth(active_cell_data, app.SmoothingFactorEditField.Value);
    
                % Oscillations Properties
                normsig = (smooth_cell_data - min(smooth_cell_data)) / (max(smooth_cell_data) - min(smooth_cell_data));
                mean_sig = mean(normsig);
                max_sig = max(normsig);
                std_sig = std(normsig);
                std_th = 2;
    
                if mean_sig > 0.38
                    std_th = 10;
                end
    
                %Oligo Oscillations
                O_duration_min = app.O_MinimalDuration.Value;
                O_duration_max = app.O_MaximalDuration.Value;
                O_prominence_min = app.O_MinimalProminence.Value;
                O_dis_min = app.O_MinimalDistance.Value;
                O_amp_min = max_sig * 0.5;
    
                %Neuron (Undefinaed) Oscillations
                N_duration_min = 5;
                N_duration_max = 21;
                N_prominence_min = 0.4;
                N_amp_min = max_sig * 0.5; 
    
                is_peaks = 0;
                is_N_peaks = 0;
                try
                    [O_pks, O_locs, O_w, O_p] = findpeaks(normsig, time_vec, 'MinPeakWidth', O_duration_min, 'MaxPeakWidth', O_duration_max, 'MinPeakProminence', O_prominence_min, 'MinPeakHeight', O_amp_min, 'MinPeakDistance', O_dis_min);
                    [N_pks, N_locs, N_w, N_p] = findpeaks(normsig, time_vec, 'MinPeakWidth', N_duration_min, 'MaxPeakWidth', N_duration_max, 'MinPeakProminence', N_prominence_min, 'MinPeakHeight', N_amp_min);
                    n_O_pks = length(O_pks);
                    n_N_pks = length(N_pks);
                    if ~isempty(O_pks)
                        if isempty(N_pks)
                            is_peaks = 1;
                        elseif n_O_pks >= n_N_pks
                            is_peaks = 1;
                        elseif n_O_pks < n_N_pks
                            is_N_peaks = 1;
                        end
                    else
                        if ~isempty(N_pks)
                            is_N_peaks = 1;
                        end
                    end
                catch
                    warning('No peaks for cell number %d', cell_num)
                end
    
                % Linear Spline interpolation
                inter_points = [admn_idx, admn_idx + 30, length(normsig)]; % for glu_inj=60: [40 140 550] % for glu_inj=120: [100 200 850]
                if any(~isfinite(inter_points))
                    disp('Invalid interpolation points.');
                    return;
                end
                inter_func = interp1(time_vec, normsig, inter_points);
                slope = diff(inter_func) ./ diff(inter_points);
    
                % neurons(Undefined)
                if is_N_peaks == 1  
                    app.Undefined_sig(:, end + 1) = smooth_cell_data;
                % multi peak signal 
                elseif is_peaks == 1 
                    if mean(active_smooth_cell_data) > 0.1
                        app.Oscillatory_sig(:, end + 1) = smooth_cell_data;
                    else
                        if O_p(1) >= 0.5 %|| std(active_smooth_cell_data) > 0.1
                            app.Oscillatory_sig(:, end + 1) = smooth_cell_data;
                        end
                    end
                % Low Plateus Signals
                elseif mean(active_smooth_cell_data) < 0.1
                    app.LowPlateus_sig(:, end + 1) = smooth_cell_data;         
                % Plateau Signals (Sharp)
                elseif slope(1) >= 0.016 %0.033 = raise of 0.5% in 30 sec
                    app.Plateau_sig(:, end + 1) = smooth_cell_data;   
                % Plateau Signals (HighFlat)
                elseif slope(1) < 0.016 && slope(1) > 0
                    app.Plateau_sig(:, end + 1) = smooth_cell_data;
                else
                    app.Undefined_sig(:, end + 1) = smooth_cell_data; 
                end
                app.num_cells = app.num_cells + 1;
            end
    
            % Define a struct array for the signals and their corresponding axes and titles
            plotData = struct(...
                'signal', {app.Oscillatory_sig, app.LowPlateus_sig, app.Plateau_sig, app.Undefined_sig}, ...
                'axes', {app.AxesOscillatory, app.AxesLowActivity, app.AxesPlateauResponse, app.AxesUndefined}, ...
                'title', {'Oscillatory Signals', 'Low Activity', 'Plateau Response', 'Undefined Signals'} ...
            );

            % Loop over the plotData array to plot the signals
            for i = 1:length(plotData)
                if ~isempty(plotData(i).signal)
                    % Plot the signals
                    plot_handles = plot(plotData(i).axes, time_vec, plotData(i).signal);
                    title(plotData(i).axes, plotData(i).title);
                    xlabel(plotData(i).axes, 'Time [Sec]');
                    ylabel(plotData(i).axes, '\Delta F / F [%]');
                    
                    % Set the ButtonDownFcn for each line
                    for j = 1:length(plot_handles)
                        plot_handles(j).ButtonDownFcn = @(src, event) app.lineSelected(src, event);
                    end
                end
            end
        end

        
 % Callback for updating plots based on parameters
        function updatePlots(app)
            % Get current parameter values
            time_to_analyze = app.TimeToAnalyzeEditField.Value;
            time_to_baseline = app.TimeToBaselineEditField.Value;
            if time_to_analyze > time_to_baseline
                analysisTime = app.TimeToAnalyzeEditField.Value - 30;
            elseif time_to_analyze < time_to_baseline
                app.MessageLabel_8.Text = 'Error: The analysis time must include the baseline time.';
            elseif time_to_analyze == time_to_baseline
                analysisTime = app.TimeToAnalyzeEditField.Value;
            end
          
            trimmingFactor = app.TrimmingEditField.Value;

            % Ensure all values are valid
            if isempty(app.ExcelData) || isempty(app.TimeVec)
                disp('Excel data or Time vector is empty.');
                return;
            end
            % Update the plots using the current parameter values
            dt = app.FrameRateEditField.Value; % frame rate
            app.preprocessAndPlot(app.ExcelData, app.TimeVec, analysisTime, trimmingFactor, dt);
        end

        % Callback function to handle line selection
        function lineSelected(app, src, event)
        % Reset the properties of the line if it is already selected
        if isequal(src.LineWidth, 2) && isequal(src.Color, [1 0 0])
            src.LineWidth = 1;  % Default line width
            src.Color = [0 0 1];  % Default line color (blue)
            app.SelectedLine = [];
        else
            % Reset the properties of all lines to default
            lines = findall(src.Parent, 'Type', 'Line');
            for i = 1:length(lines)
                lines(i).LineWidth = 1;  % Default line width
                lines(i).Color = [0 0 1];  % Default line color (blue)
            end

            % Highlight the selected line
            src.LineWidth = 2;  % Thicker line width
            src.Color = [1 0 0];  % Change color to red
            
            % Store the selected line and its original properties
            app.SelectedLine = src;
            app.OriginalAxes = src.Parent;
            app.OriginalXData = src.XData;
            app.OriginalYData = src.YData;
            
            % Set window button motion and button up functions
            app.UIFigure.WindowButtonMotionFcn = @(~,~) app.dragLine();
            app.UIFigure.WindowButtonUpFcn = @(~,~) app.releaseLine();
        end
    end

    % Function to handle line dragging
    function dragLine(app)
    if isempty(app.SelectedLine)
        return;
    end

    % Get current mouse position in figure units
    currentPoint = get(app.UIFigure, 'CurrentPoint');
    
    % Convert figure units to axes units
    ax = app.SelectedLine.Parent;
    axPos = ax.Position;
    figPos = app.UIFigure.Position;
    
    % Get normalized mouse position in the figure
    normX = currentPoint(1) / figPos(3);
    normY = currentPoint(2) / figPos(4);
    disp(['Mouse position (normalized): ', num2str(normX), ', ', num2str(normY)]);

    
    % Convert normalized mouse position to axes coordinates
    xlim = ax.XLim;
    ylim = ax.YLim;
    
    newXData = normX * (xlim(2) - xlim(1)) + xlim(1);
    newYData = normY * (ylim(2) - ylim(1)) + ylim(1);
    
    % Update line data
    app.SelectedLine.XData = newXData;
    app.SelectedLine.YData = newYData;
end

    % Function to handle line release
    function releaseLine(app)
    if isempty(app.SelectedLine)
        return;
    end

    % Get current mouse position in figure units
    currentPoint = get(app.UIFigure, 'CurrentPoint');
    
    % Determine the new axes based on the current mouse position
    newAxes = [];
    allAxes = [app.AxesOscillatory, app.AxesLowActivity, app.AxesPlateauResponse, app.AxesUndefined];
    
    for ax = allAxes
        % Get axes position in figure units
        axPos = ax.Position;
        
        % Check if the current mouse position is within the axes
        if currentPoint(1) >= axPos(1) && currentPoint(1) <= axPos(1) + axPos(3) && ...
           currentPoint(2) >= axPos(2) && currentPoint(2) <= axPos(2) + axPos(4)
            newAxes = ax;
            break;
        end
    end
    
    % If a new axes is found, update the parent of the selected line
    if ~isempty(newAxes)
        % Convert current line data to new axes coordinates
        newXData = app.SelectedLine.XData;
        newYData = app.SelectedLine.YData;

        % Create a new line in the new axes
        newLine = line(newAxes, newXData, newYData, 'Color', app.SelectedLine.Color, ...
            'LineWidth', app.SelectedLine.LineWidth, 'ButtonDownFcn', @(src, event) app.lineSelected(src, event));
        
        % Delete the old line
        delete(app.SelectedLine);
        
        % Update the selected line to the new line
        app.SelectedLine = newLine;
        disp('Line moved to new axes.');
    else
        % Revert the line to its original position
        app.SelectedLine.XData = app.OriginalXData;
        app.SelectedLine.YData = app.OriginalYData;
        disp('Line reverted to original position.');
    end
    
    % Reset the selected line properties
    app.SelectedLine.LineWidth = 1;  % Default line width
    app.SelectedLine.Color = [0 0 1];  % Default line color (blue)
    
    % Clear the selected line and reset window functions
    app.SelectedLine = [];
    app.UIFigure.WindowButtonMotionFcn = '';
    app.UIFigure.WindowButtonUpFcn = '';
    end
    
    function setDefaultParameters(app)
        app.O_MinimalDistance.Value = 60;
        app.O_MinimalProminence.Value = 0.3;
        app.O_MaximalDuration.Value = 180;
        app.O_MinimalDuration.Value = 21;
        app.SmoothingFactorEditField.Value = 15;
        app.TimeToAnalyzeEditField.Value = 120;
        app.TimeToBaselineEditField.Value = 120;
        app.FrameRateEditField.Value = 1.08;
        app.TrimmingEditField.Value = 10;
    end
    %% Feature extruction 
    function osci_struct = processFiles(app, files, path, n, condition)
    osci_struct = struct;
    N_TotalROI = 1;
    
    for i = 1:n
        try
            % Load the MAT file
            CurrFile = load(fullfile(path, files{i}));
            fprintf('Current File: %s\n', files{i});
        catch
            warning('File %s does not exist.', files{i});
            continue;
        end
        
        % Oscillation analysis
        try
            CurrMat = CurrFile.Oscillatory_sig;
            time_vec = CurrFile.time_vec';
        catch
            warning('Oscillatory signal does not exist in %s.', files{i});
            continue;
        end
        
        [osci_struct, N_TotalROI] = app.creat_osci_struct(CurrMat, osci_struct, time_vec, N_TotalROI, condition, i);
    end
end

    function [osci_struct, TotalCell_final] = creat_osci_struct(app, CurrMat, osci_struct, time_vec, N_TotalROI, condition, i)
    for j = 1:size(CurrMat, 2)
        osci_struct(N_TotalROI).ID = sprintf('%s%d', condition, i); % Mouse ID
        osci_struct(N_TotalROI).Genotype = sprintf('ROI_#%d', N_TotalROI); % ROI number
        osci_struct(N_TotalROI).Signal = CurrMat(:, j)'; % Cell calcium trace signal
        sig = osci_struct(N_TotalROI).Signal;
        time = round(time_vec);
        fprintf('ROI number: %d\n', N_TotalROI);
        
        if size(CurrMat, 1) < 600
            glut_inj_idx = find(time == 60); % Find glutamate administration index
        else
            glut_inj_idx = find(time == 120);
        end
        
        osci_struct(N_TotalROI).Time_vec = time_vec;
        osci_struct(N_TotalROI).GlutInj_idx = glut_inj_idx;
        % Call to the external function analys_oscillations
        [n_spike, f_spike, d_spike, p_spike, max_sig, p_spike_norm, amp_spike, mean_amp_spike, areaAmp_base, areaAmp_prom, onset_time, onset_idx, offset_time, offset_idx, rise_time30, decay_time30, timeLat, areaLat_prom, areaLat_base, duration, area_prom, area_base, inter_peak_intervals] = analys_oscillations(sig, time_vec, glut_inj_idx, 0);
        osci_struct(N_TotalROI).N_peaks = n_spike;
        osci_struct(N_TotalROI).N_peaks_minut = f_spike;
        osci_struct(N_TotalROI).Max_intensity = max_sig;
        osci_struct(N_TotalROI).Prominence = p_spike;
        osci_struct(N_TotalROI).NormProminence = p_spike_norm;
        osci_struct(N_TotalROI).NormProminence_mean_AllPeaks = mean(p_spike_norm); % Mean prominence (all spikes)
        osci_struct(N_TotalROI).Amplitude = amp_spike;
        osci_struct(N_TotalROI).Amplitude_mean_AllPeaks = mean(amp_spike); % Mean amplitude (all spikes)
        osci_struct(N_TotalROI).MeanAmp = mean_amp_spike; % Amplitude in 3 time frames around the maxima
        osci_struct(N_TotalROI).AreaAmp_0Base = areaAmp_base;
        osci_struct(N_TotalROI).AreaAmp_30Prom = areaAmp_prom;
        osci_struct(N_TotalROI).Latency_peak = timeLat;
        osci_struct(N_TotalROI).AreaLat_30Prom = areaLat_prom;
        osci_struct(N_TotalROI).AreaLat_0Base = areaLat_base;
        osci_struct(N_TotalROI).Interval__between_peaks = d_spike;
        osci_struct(N_TotalROI).Duration_30Prom = duration; % Spike duration (area base)
        osci_struct(N_TotalROI).RiseTime = rise_time30; % Rise time of peaks 30% to 90% prominence
        osci_struct(N_TotalROI).DecayTime = decay_time30; % Decay time of peaks 90% to 30% prominence
        osci_struct(N_TotalROI).OnsetTime = onset_time;
        osci_struct(N_TotalROI).OnsetIdx = onset_idx;
        osci_struct(N_TotalROI).OffsetTime = offset_time;
        osci_struct(N_TotalROI).OffsetIdx = offset_idx;
        osci_struct(N_TotalROI).InterPeaks_intervals = inter_peak_intervals;
        osci_struct(N_TotalROI).Area_30Prom = area_prom; % Area of all spikes
        osci_struct(N_TotalROI).Area_0Base = area_base; % Area of all spikes
        osci_struct(N_TotalROI).Area_AllPeaks_30Prom = sum(area_prom); % Total area of all spikes (sum all areas)
        osci_struct(N_TotalROI).Area_AllPeaks_30Base = sum(area_base); % Total area of all spikes (sum all areas)
        
        fields = {'Prom_%d_peak', 'NormProm_%d_peak', 'Amp_%d_peak', 'MeanAmp_%d_peak', 'AreaAmp_0base_%d_peak', 'AreaAmp_30prom_%d_peak', 'Lat_%d_peak', 'AreaLat_30prom_%d_peak', 'Inter_peaks_%d', 'Duration_30Prom_%d', 'RiseTime_%d', 'DecayTime_%d', 'OnsetTime_%d', 'OffsetTime_%d', 'Inter_%d_Off_On', 'Area_30Prom_%d', 'Area_0Base_%d'};
        dataset = {p_spike, p_spike_norm, amp_spike, mean_amp_spike, areaAmp_base, areaAmp_prom, timeLat, areaLat_prom, d_spike, duration, rise_time30, decay_time30, onset_time, offset_time, inter_peak_intervals, area_prom, area_base};
        for f = 1:length(fields)
            for j = 1:n_spike
                field = sprintf(fields{f}, j);
                data = dataset{f};
                if length(data) >= j
                    osci_struct(N_TotalROI).(field) = data(j);
                else
                    osci_struct(N_TotalROI).(field) = [];
                end
            end
        end
        
        N_TotalROI = N_TotalROI + 1;
    end
    TotalCell_final = N_TotalROI;
    end

    function valid = validateFiles(app, files, condition)
        % Initialize the validity to true
        valid = true;
        
        % Determine the expected prefix based on the condition
        if strcmpi(condition, 'mut')
            prefix = 'mut';
            otherPrefix = 'ctr';
        elseif strcmpi(condition, 'ctr')
            prefix = 'ctr';
            otherPrefix = 'mut';
        else
            valid = false;
            return;
        end
        
        % Check each file to ensure it matches the expected prefix
        for i = 1:length(files)
            if startsWith(files{i}, otherPrefix, 'IgnoreCase', true)
                uialert(app.UIFigure, sprintf('Selected files have mixed prefixes: expected %s but found %s in file %s', prefix, otherPrefix, files{i}), 'Validation Error');
                valid = false;
                return;
            elseif ~startsWith(files{i}, prefix, 'IgnoreCase', true)
                uialert(app.UIFigure, sprintf('Selected files do not match the chosen condition. Expected prefix: %s, but found %s in file %s', prefix, files{i}), 'Validation Error');
                valid = false;
                return;
            end
        end
    end
%% Sanity check 


end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % Set the default condition based on the initial selected button
            selectedButton = app.conditionButtonGroup.SelectedObject;
            app.condition = selectedButton.Text;
            if strcmp(app.condition, 'Mutant')
                app.condition = 'mut';
            elseif strcmp(app.condition, 'Control')
                app.condition = 'ctr';
            end
        
            % Set both panels to full-screen size
            app.landingScreen.Position = [1, 1, app.UIFigure.Position(3), app.UIFigure.Position(4)];
            app.TabGroup.Position = [1, 1, app.UIFigure.Position(3), app.UIFigure.Position(4)];
        
            % Ensure only LandingPanel is visible initially
            app.landingScreen.Visible = 'on';
            app.TabGroup.Visible = 'off';
            % ⏳ Wait 1 second before playing narration
            pause(3);
            % ✅ Add the audio playback line here:
            try
                [y, fs] = audioread('welcome_message1.wav');  % Or whatever your file is named
                sound(y, fs);
            catch ME
                disp('Failed to play narration:');
                disp(ME.message);
            end
        end

        % Button pushed function: LoadYourFileButton
        function LoadYourFileButtonPushed(app, event)
            disp('Button clicked.');
                    % Open file dialog to select an XLSX file
                    app.MessageLabel.Text = 'LOADING...';
                    [file, path] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, 'Select an Excel file');
                    disp('File dialog closed.');
                    if isequal(file, 0)
                        disp('File selection canceled.');
                        app.MessageLabel.Text = 'File selection canceled.';
                    else
                        % Read the XLSX file
                        filepath = fullfile(path, file);
                        disp(['Selected file: ', filepath]);
                        try
                            app.ExcelData = readmatrix(filepath);
                            app.TimeVec = app.ExcelData(:,1);
                            if isempty(app.TimeVec)
                                error('Time vector is empty.');
                            end
                            trim = app.TrimmingEditField.Value; %trimming time [sec]
                            app.TimeVec = app.TimeVec(trim:end);
                            dt = app.FrameRateEditField.Value; % frame rate
                            app.ExcelData = app.ExcelData(:,3:2:end); % indexing only intensity of each ROI
                            if isempty(app.ExcelData)
                                error('Excel data is empty.');
                            end
                            
                            % Update the UI to indicate success
                            disp('Excell file successfully loaded.');
                            app.MessageLabel.Text = 'Excell file successfully loaded.';
                            app.file = file;
                            app.DefaultiveCheckBox.Visible = 'on';

                            
                            % Call the preprocessing function
                            time_to_analyze = app.TimeToAnalyzeEditField.Value;
                            time_to_baseline = app.TimeToBaselineEditField.Value;
                            if time_to_analyze > time_to_baseline
                                admn_time = app.TimeToAnalyzeEditField.Value - 30;
                            elseif time_to_analyze < time_to_baseline
                                app.MessageLabel_8.Text = 'Error: The analysis time must include the baseline time.';
                            elseif time_to_analyze == time_to_baseline
                                admn_time = app.TimeToAnalyzeEditField.Value;
                            end
                                app.preprocessAndPlot(app.ExcelData, app.TimeVec, admn_time, trim, dt);
                        catch ME
                            disp(['Error loading file: ', ME.message]);
                            app.MessageLabel.Text = ['Error loading file: ', ME.message];
                        end
                        app.Pleaseupload_Label.Visible = "off";
                        app.Startbyloading_Label.Visible = "off";
                        app.Hereyouwillsee_Label.Visible = "off";
                        app.MessageLabel.Position = [476,457,202,23]
                        app.LoadYourFileButton.Position = [517,483,119,30]
                        app.AxesOscillatory.Visible = 'on'
                        app.AxesLowActivity.Visible = 'on'
                        app.AxesPlateauResponse.Visible = 'on'
                        app.AxesUndefined.Visible = 'on'
                        app.elipsis.Visible = "on"
                        app.elipsis_Low.Visible = "on"
                        app.elipsis_Plateau.Visible = "on"
                        app.elipsis_Undefined.Visible = "on"
                        app.elipsis_Oscilatory.Visible = "on"
                    end
        end

        % Value changed function: TimeToAnalyzeEditField
        function TimeToAnalyzeEditFieldValueChanged(app, event)
                %app.updatePlots();
        end

        % Value changed function: SmoothingFactorEditField
        function SmoothingFactorEditFieldValueChanged(app, event)
                %app.updatePlots();
        end

        % Value changed function: TrimmingEditField
        function TrimmingEditFieldValueChanged(app, event)
                %app.updatePlots();         
        end

        % Callback function
        function N_MinimalProminenceValueChanged(app, event)
                %app.updatePlots();           
        end

        % Callback function
        function N_MaximalDurationValueChanged(app, event)
                %app.updatePlots();         
        end

        % Callback function
        function N_MinimalDurationValueChanged(app, event)
                %app.updatePlots();         
        end

        % Value changed function: O_MinimalDistance
        function O_MinimalDistanceValueChanged(app, event)
                %app.updatePlots();          
        end

        % Value changed function: O_MinimalProminence
        function O_MinimalProminenceValueChanged(app, event)
                %app.updatePlots();      
        end

        % Value changed function: O_MaximalDuration
        function O_MaximalDurationValueChanged(app, event)
                %app.updatePlots();          
        end

        % Value changed function: O_MinimalDuration
        function O_MinimalDurationValueChanged(app, event)
                %app.updatePlots();
        end

        % Callback function
        function SavematfileButtonPushed(app, event)
            % Get the file name and path from the user
            app.MessageLabel_4.Text = 'LOADIND...'
            [file, path] = uiputfile('*.mat', 'Save MAT File');
            % Check if the user did not cancel the dialog
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation');
                app.MessageLabel_4.Text = 'File saving canceled.';
                return;
            else
                fullFilePath = fullfile(path, file);
                
                % Save the required variables to the .mat file
                Plateau = app.Plateau_sig;
                ID = app.file;
                LowPlateus = app.LowPlateus_sig;
                Oscillatory_sig = app.Oscillatory_sig;
                Undefined = app.Undefined_sig;
                num_cells = app.num_cells;
                time_vec = app.TimeVec;
                
                % Ensure num_cells is properly defined and not zero
                if num_cells > 0
                    perc_Plateau = (size(Plateau, 2) / num_cells) * 100;
                    perc_LowPlateus = (size(LowPlateus, 2) / app.num_cells) * 100;
                    perc_ocillatory = ((size(Oscillatory_sig, 2) + size(Plateau_sig, 2)) / num_cells) * 100;
                else
                    perc_Plateau = 0;
                    perc_LowPlateus = 0;
                    perc_ocillatory = 0;
                    warning('num_cells is zero or not properly defined. Percentages set to zero.');
                end
            
                % Save all the variables in a structure
                save(fullFilePath, 'Plateau', 'ID', 'LowPlateus', 'Oscillatory_sig', 'Undefined', 'num_cells', 'perc_Plateau', 'perc_LowPlateus', 'perc_ocillatory', 'time_vec');
                app.MessageLabel_4.Text = 'File saved successfully'

                % Display a message indicating successful save
                disp(['File saved successfully to ', fullFilePath]);
            end
        end

        % Callback function
        function SaveplotsButtonPushed(app, event)
            % Get the file name and path from the user
            app.MessageLabel_5.Text = 'LOADIND...'
            [file, path] = uiputfile('*.png', 'Save plots');
            % Check if the user did not cancel the dialog
            if isequal(file, 0) || isequal(path, 0)
                app.MessageLabel_5.Text = 'File saving canceled.';
                disp('User canceled the save operation');
                return;
            else
                fullFilePath = fullfile(path, file);
                
                % Create a new figure to capture all plots
                hFig = figure('Visible', 'off', 'Position', [100, 100, 1200, 800]);
                t = tiledlayout(hFig, 3, 2, 'TileSpacing', 'compact', 'Padding', 'compact');

                % Plot Oscillatory Signals
                nexttile(t);
                copyobj(app.AxesOscillatory.Children, gca);
                title('Oscillatory Signals');
                xlabel('Time[sec]');
                ylabel('ΔF/F[%]');

                % Plot Low Activity Signals
                nexttile(t);
                copyobj(app.AxesLowActivity.Children, gca);
                title('Low Activity');
                xlabel('Time[sec]');
                ylabel('ΔF/F[%]');


                % Plot Plateau Response Signals
                nexttile(t);
                copyobj(app.AxesPlateauResponse.Children, gca);
                title('Plateau Response');
                xlabel('Time[sec]');
                ylabel('ΔF/F[%]');

                % Plot Undefined Signals
                nexttile(t);
                copyobj(app.AxesUndefined.Children, gca);
                title('Undefined Signals');
                xlabel('Time[sec]');
                ylabel('ΔF/F[%]');

                % Save the figure to a PNG file
                exportgraphics(hFig, fullFilePath);
                close(hFig);

                % Display a message indicating successful save
                disp(['Plots saved successfully to ', fullFilePath]);
                app.MessageLabel_5.Text = 'File saved successfully'

            end
        end

        % Selection changed function: conditionButtonGroup
        function conditionButtonGroupSelectionChanged(app, event)
            selectedButton = app.conditionButtonGroup.SelectedObject;
            app.condition = selectedButton.Text;
            if strcmp(app.condition, 'Mutant')
                app.condition = 'mut'
            elseif strcmp(app.condition, 'Control')
                app.condition = 'ctr';
            end
        end

        % Button pushed function: ChoosematfilesButton
        function ChoosematfilesButtonPushed2(app, event)
            % Open file selection dialog
            app.MessageLabel_2.Text = 'LOADING...';
            [files, path] = uigetfile('*.mat', 'Select MAT-files', 'MultiSelect', 'on');
            
            if isequal(files, 0)
               app.MessageLabel_2.Text = 'File selection canceled.';
               return;
            end
            if ischar(files)
                files = {files};
            end
            
            n = length(files);

            % Check the condition
            condition = app.condition;
            disp(condition)
            
            % Validate selected files
            valid = app.validateFiles(files, condition);
            if ~valid
            app.MessageLabel_2.Text = '';
            end
            if valid
                app.MessageLabel_2.Text = 'Mat file/s succesfully loaded.';
    
                % Process files based on the condition
                if strcmp(condition, 'mut')
                    app.osci_mut = processFiles(app, files, path, n, 'mut');
                else
                    app.osci_ctr = processFiles(app, files, path, n, 'ctr');
                end
                app.MessageLabel_2.Text = 'Mat file/s succesfully processed.';
            app.SaveFeaturesButton.Visible = 'on'    
            end
        end

        % Button pushed function: SaveFeaturesButton
        function SaveFeaturesButtonPushed2(app, event)
            % Get the file name and path from the user
            app.MessageLabel_3.Text = 'LOADING...';
            [file, path] = uiputfile('*.mat', 'Save MAT File');
            
            % Check if the user did not cancel the dialog
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation');
                app.MessageLabel_3.Text = 'File saving canceled.';
                return;
            else
                fullFilePath = fullfile(path, file);
                
                % Get the condition from the app
                condition = app.condition
                
                % Determine the structure based on the condition
                if strcmp(condition, 'mut')
                    myStruct = app.osci_mut;
                else
                    myStruct = app.osci_ctr;
                end
                
                % Check if myStruct is indeed a structure
                if ~isstruct(myStruct)
                    disp(class(myStruct));
                    error('Expected myStruct to be a structure, but it is not.');
                end
                
                % Save the struct to a .mat file
                save(fullFilePath, 'myStruct');
                
                % Display a message indicating successful save
                app.MessageLabel_3.Text = 'File saved successfully';
                disp(['File saved successfully to ', fullFilePath]);
            end
        end

        % Button pushed function: ChoosematfileButton
        function ChoosematfileButtonPushed(app, event)
            % Open file selection dialog
            app.MessageLabel_6.Text = 'LOADING...';
            [file, path] = uigetfile('*.mat', 'Select MAT-files', 'MultiSelect', 'on');
            
            if isequal(file, 0)
               app.MessageLabel_6.Text = 'File selection canceled.';
               return;
            else
            data_mat = fullfile(path, file);
            data_struct = load(data_mat); % Load the MAT file
            data_struct = data_struct.myStruct; % Access the struct inside the MAT file            time_vec=data_struct.Time_vec;
            
            % Get the data from the struct
            time_vec = data_struct.Time_vec;
            cell_num =  app.ChooseCellNumberEditField_2.Value

            
            % Check if cell_num exists within data_struct
            if cell_num > numel(data_struct)
                app.MessageLabel_6.Text = sprintf('Error: Cell number %d exceeds available data.', cell_num);
                return;
            end          
           
            sig = data_struct(cell_num).Signal;
          
            % Compute peak parameters        
            max_sig=max(sig); %max signal amplitude
            normsig = (sig-min(sig))/(max(sig)-min(sig));
            O_duration_min = app.O_MinimalDuration.Value; %sec
            O_duration_max = app.O_MaximalDuration.Value; %sec
            O_prominence_min = app.O_MinimalProminence.Value; 
            O_dis_min = app.O_MinimalDistance.Value; %sec
            O_amp_min = max(max_sig)*0.5;

            % Clear existing plots on the UIAxes
            cla(app.sanityAxes);

            % Plot the signal on the UIAxes
            plot(app.sanityAxes, time_vec, sig);
            hold(app.sanityAxes, 'on');
            
            % Find and plot peaks
            [peaks, locs, widths, prominences] = findpeaks(sig, time_vec, 'MinPeakWidth', O_duration_min, 'MaxPeakWidth', O_duration_max, ...
                'MinPeakProminence', O_prominence_min, 'MinPeakHeight', O_amp_min, 'MinPeakDistance', O_dis_min, 'Annotate', 'extents', 'WidthReference', 'halfprom');
            
            % Plot peaks on the UIAxes
            plot(app.sanityAxes, locs, peaks, 'ro'); % Red circles for peaks
            
            % Add labels and title to the UIAxes
            xlabel(app.sanityAxes, 'Time');
            ylabel(app.sanityAxes, 'Signal');
            title(app.sanityAxes, 'Signal and Detected Peaks');
            
            % Update message label
            app.MessageLabel_6.Text = 'File loaded and plot updated.';
            
            hold(app.sanityAxes, 'off');
            app.sanityAxes.Visible = 'on'
            app.SaveplotButton.Visible = 'on'
            end    
        end

        % Button pushed function: SaveplotButton
        function SaveplotButtonPushed(app, event)
            % Get the file name and path from the user
            app.MessageLabel_7.Text = 'LOADIND...'
            [file, path] = uiputfile('*.png', 'Save plots');
            % Check if the user did not cancel the dialog
            if isequal(file, 0) || isequal(path, 0)
                app.MessageLabel_7.Text = 'File saving canceled.';
                disp('User canceled the save operation');
                return;
            else
                fullFilePath = fullfile(path, file);
                
                % Create a new figure
                hFig = figure('Visible', 'off', 'Position', [100, 100, 1200, 800]);
                t = tiledlayout(hFig, 3, 2, 'TileSpacing', 'compact', 'Padding', 'compact');

                % Plot Oscillatory Signals
                ax = nexttile(t);
                copyobj(app.sanityAxes.Children, ax);
                xlabel(ax, 'Time');
                ylabel(ax, 'Signal');
                title(ax, 'Signal and Detected Peaks');

                % Save the figure to a PNG file
                exportgraphics(hFig, fullFilePath);
                close(hFig);

                % Display a message indicating successful save
                disp(['Plot saved successfully to ', fullFilePath]);
                app.MessageLabel_7.Text = 'plot saved successfully'
            end
        end

        % Value changed function: DefaultiveCheckBox
        function DefaultiveCheckBoxValueChanged(app, event)
            value = app.DefaultiveCheckBox.Value;
             if value == 0
                % Show the panel when checkbox is unchecked
                app.ParameterPanel.Visible = 'on';
                app.DefaultiveCheckBox.Enable = 'off';  % Disable the checkbox
            else
                % Hide the panel when checkbox is checked
                app.ParameterPanel.Visible = 'off';
                setDefaultParameters(app);
                app.updatePlots();
             end
        end

        % Button pushed function: ApplyChngesButton
        function ApplyChngesButtonPushed(app, event)
            time_to_analyze = app.TimeToAnalyzeEditField.Value;
            time_to_baseline = app.TimeToBaselineEditField.Value;
            if time_to_analyze < time_to_baseline
                app.MessageLabel_8.Text = sprintf('Error: The analysis time must\ninclude the baseline time.');             
            else
            app.DefaultiveCheckBox.Value = 0;
            app.ParameterPanel.Visible = 'off';
            app.updatePlots();
            app.DefaultiveCheckBox.Enable = 'on';   % Re-enable the checkbox
            app.MessageLabel_8.Text = '';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.DefaultiveCheckBox.Value = 1;
            app.ParameterPanel.Visible = 'off';
            setDefaultParameters(app);
            app.updatePlots();
            app.DefaultiveCheckBox.Enable = 'on';   % Re-enable the checkbox
            app.MessageLabel_8.Text = '';
        end

        % Value changed function: TimeToBaselineEditField
        function TimeToBaselineEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: BatchAnalysisButton
        function BatchAnalysisButtonPushed(app, event)
                app.landingScreen.Visible = 'off'
                app.TabGroup.Visible = 'on';
                app.TabGroup.SelectedTab = app.FeatureExtractionTab;
        end

        % Button pushed function: SingleFileButton
        function SingleFileButtonPushed(app, event)
                app.landingScreen.Visible = 'off'
                app.TabGroup.Position = [1, 1, app.UIFigure.Position(3), app.UIFigure.Position(4)];
                app.TabGroup.Visible = 'on';
                app.TabGroup.SelectedTab = app.PreprocessingTab;
        end

        % Callback function
        function SanityCheckButtonPushed(app, event)
                app.landingScreen.Visible = 'off'                
                app.TabGroup.Visible = 'on';
                app.TabGroup.SelectedTab = app.SanityCheckTab;
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
         % Get the current size of the UIFigure
            newSize = app.UIFigure.Position;
            % Update the position of the visible panel
            if app.landingScreen.Visible
                app.landingScreen.Position = [1, 1, newSize(3), newSize(4)];
            elseif app.TabGroup.Visible
                app.TabGroup.Position = [1, 1, newSize(3), newSize(4)];
            end
        end

        % Button pushed function: elipsis
        function elipsisButtonPushed(app, event)
            % Check if ExportAs is already visible
            if strcmp(app.ExportAs.Visible, 'on')
                % If ExportAs is visible, make it invisible
                app.ExportAs.Visible = 'off';
            else
                % If ExportAs is not visible, make it visible
                app.ExportAs.Visible = 'on';   
            end
        end

        % Value changed function: ExportAs
        function ExportAsValueChanged(app, event)
            selectedValue = app.ExportAs.Value;
            switch selectedValue
                case 'Export as MAT'
                    % Execute MAT export logic
                    [file, path] = uiputfile('*.mat', 'Save MAT File');
                    
                    % Check if the user did not cancel the dialog
                    if isequal(file, 0) || isequal(path, 0)
                        disp('User canceled the save operation');
                        return;
                    else
                        fullFilePath = fullfile(path, file);
                        
                        % Save the required variables to the .mat file
                        ID = app.file;
                        Plateau_sig = app.Plateau_sig;
                        LowPlateus = app.LowPlateus_sig;
                        Oscillatory_sig = app.Oscillatory_sig;
                        Undefined = app.Undefined_sig;
                        num_cells = app.num_cells;
                        time_vec = app.TimeVec;
                        
                        % Ensure num_cells is properly defined and not zero
                        if num_cells > 0
                            perc_Plateau = (size(Plateau_sig, 2) / num_cells) * 100;
                            perc_LowPlateus = (size(LowPlateus, 2) / app.num_cells) * 100;
                            perc_ocillatory = ((size(Oscillatory_sig, 2) + size(Plateau_sig, 2)) / num_cells) * 100;
                        else
                            perc_Plateau = 0;
                            perc_LowPlateus = 0;
                            perc_ocillatory = 0;
                            warning('num_cells is zero or not properly defined. Percentages set to zero.');
                        end
                    
                        % Save all the variables in a structure
                        save(fullFilePath, 'Plateau_sig', 'ID', 'LowPlateus', 'Oscillatory_sig', 'Undefined', 'num_cells', 'perc_Plateau', 'perc_LowPlateus', 'perc_ocillatory', 'time_vec');
        
                        % Display a message indicating successful save
                        disp(['File saved successfully to ', fullFilePath]);
                    end
                    
                case 'Export as JPG'
                    % Execute JPG export logic
                    [file, path] = uiputfile('*.jpg', 'Save plots');
                    
                    % Check if the user did not cancel the dialog
                    if isequal(file, 0) || isequal(path, 0)
                        disp('User canceled the save operation');
                        return;
                    else
                        fullFilePath = fullfile(path, file);
                        
                        % Create a new figure to capture all plots
                        hFig = figure('Visible', 'off', 'Position', [100, 100, 1200, 800]);
                        t = tiledlayout(hFig, 3, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
        
                        % Plot Oscillatory Signals
                        nexttile(t);
                        copyobj(app.AxesOscillatory.Children, gca);
                        title('Oscillatory Signals');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Plot Low Activity Signals
                        nexttile(t);
                        copyobj(app.AxesLowActivity.Children, gca);
                        title('Low Activity');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Plot Plateau Response Signals
                        nexttile(t);
                        copyobj(app.AxesPlateauResponse.Children, gca);
                        title('Plateau Response');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Plot Undefined Signals
                        nexttile(t);
                        copyobj(app.AxesUndefined.Children, gca);
                        title('Undefined Signals');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Save the figure to a JPG file
                        exportgraphics(hFig, fullFilePath);
                        close(hFig);
        
                        % Display a message indicating successful save
                        disp(['Plots saved successfully to ', fullFilePath]);
                    end
                    
                case 'Export as PDF'
                    % Execute PDF export logic
                    [file, path] = uiputfile('*.pdf', 'Save plots'); 
                    % Check if the user did not cancel the dialog
                    if isequal(file, 0) || isequal(path, 0)
                        disp('User canceled the save operation');
                        return;
                    else
                        fullFilePath = fullfile(path, file);
                        
                        % Create a new figure to capture all plots
                        hFig = figure('Visible', 'off', 'Position', [100, 100, 1200, 800]);
                        t = tiledlayout(hFig, 3, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
        
                        % Plot Oscillatory Signals
                        nexttile(t);
                        copyobj(app.AxesOscillatory.Children, gca);
                        title('Oscillatory Signals');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Plot Low Activity Signals
                        nexttile(t);
                        copyobj(app.AxesLowActivity.Children, gca);
                        title('Low Activity');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Plot Plateau Response Signals
                        nexttile(t);
                        copyobj(app.AxesPlateauResponse.Children, gca);
                        title('Plateau Response');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Plot Undefined Signals
                        nexttile(t);
                        copyobj(app.AxesUndefined.Children, gca);
                        title('Undefined Signals');
                        xlabel('Time[sec]');
                        ylabel('ΔF/F[%]');
        
                        % Save the figure to a PDF file
                        exportgraphics(hFig, fullFilePath, 'ContentType', 'vector');
                        close(hFig);
        
                        % Display a message indicating successful save
                        disp(['Plots saved successfully to ', fullFilePath]);
                    end
            end
        end

        % Button pushed function: elipsis_Oscilatory
        function elipsis_OscilatoryButtonPushed(app, event)
            % Check if ExportAs is already visible
            if strcmp(app.ExportAs_Oscilatory.Visible, 'on')
                % If ExportAs is visible, make it invisible
                app.ExportAs_Oscilatory.Visible = 'off';
            else
                % If ExportAs is not visible, make it visible
                app.ExportAs_Oscilatory.Visible = 'on';   
            end
        end

        % Callback function
        function elipsis_LowButtonPushed(app, event)

        end

        % Callback function
        function elipsis_PlateauButtonPushed(app, event)

        end

        % Callback function
        function elipsis_UndefinedButtonPushed(app, event)

        end

        % Button pushed function: elipsis_Plateau
        function elipsis_PlateauButtonPushed2(app, event)
            % Check if ExportAs is already visible
            if strcmp(app.ExportAs_Plateau.Visible, 'on')
                % If ExportAs is visible, make it invisible
                app.ExportAs_Plateau.Visible = 'off';
            else
                % If ExportAs is not visible, make it visible
                app.ExportAs_Plateau.Visible = 'on';   
            end
        end

        % Button pushed function: elipsis_Low
        function elipsis_LowButtonPushed2(app, event)
            % Check if ExportAs is already visible
            if strcmp(app.ExportAs_Low.Visible, 'on')
                % If ExportAs is visible, make it invisible
                app.ExportAs_Low.Visible = 'off';
            else
                % If ExportAs is not visible, make it visible
                app.ExportAs_Low.Visible = 'on';   
            end
        end

        % Button pushed function: elipsis_Undefined
        function elipsis_UndefinedButtonPushed2(app, event)
            % Check if ExportAs is already visible
            if strcmp(app.ExportAs_Undefined.Visible, 'on')
                % If ExportAs is visible, make it invisible
                app.ExportAs_Undefined.Visible = 'off';
            else
                % If ExportAs is not visible, make it visible
                app.ExportAs_Undefined.Visible = 'on';   
            end
        end

        % Value changed function: ExportAs_Oscilatory
        function ExportAs_OscilatoryValueChanged(app, event)
            selectedValue = app.ExportAs_Oscilatory.Value
            if strcmp(selectedValue, 'Extend full page')
                disp("Creating full screen plot...");
                newFig = figure('Name', 'Full Screen Plot', 'NumberTitle', 'off');
                newAxes = axes('Parent', newFig);
                copyobj(app.AxesOscillatory.Children, newAxes);
                newFig.Position = get(0, 'ScreenSize');
                xlabel(newAxes, 'Time [sec]');
                ylabel(newAxes, 'Δ F / F [%]');
                title(newAxes, 'Oscillatory Signals');
    
            elseif strcmp(selectedValue, 'save as .xlsx')
                disp("Saving as Excel...");
                [file, path] = uiputfile('*.xlsx', 'Save Oscillatory Data As');
                if isequal(file, 0)
                    disp("User canceled save dialog.");
                    return;
                end
                fullFileName = fullfile(path, file);
    
                timeVec = app.TimeVec;
                signalMatrix = app.Oscillatory_sig;
    
                dataToExport = [timeVec(:), signalMatrix];
                varNames = ['Time_sec', strcat("ROI_", string(1:size(signalMatrix, 2)))];
                T = array2table(dataToExport, 'VariableNames', varNames);
                writetable(T, fullFileName);
    
                uialert(app.UIFigure, 'Data successfully saved as Excel file.', ...
                        'Success', 'Icon', 'success');
            end
        end

        % Value changed function: ExportAs_Low
        function ExportAs_LowValueChanged(app, event)
            selectedValue = app.ExportAs_Low.Value;
                if strcmp(selectedValue, 'Extend full page')
                    newFig = figure('Name', 'Full Screen Plot - Low Activity', 'NumberTitle', 'off');
                    newAxes = axes('Parent', newFig);
                    copyobj(app.AxesLowActivity.Children, newAxes);
                    newFig.Position = get(0, 'ScreenSize');
                    xlabel(newAxes, 'Time [sec]');
                    ylabel(newAxes, 'Δ F / F [%]');
                    title(newAxes, 'Low Activity Signals');
            
                elseif strcmp(selectedValue, 'save as .xlsx')
                    [file, path] = uiputfile('*.xlsx', 'Save Low Activity Data As');
                    if isequal(file, 0)
                        return;
                    end
                    fullFileName = fullfile(path, file);
            
                    timeVec = app.TimeVec;
                    signalMatrix = app.LowPlateus_sig;
            
                    dataToExport = [timeVec(:), signalMatrix];
                    varNames = ['Time_sec', strcat("ROI_", string(1:size(signalMatrix, 2)))];
                    T = array2table(dataToExport, 'VariableNames', varNames);
                    writetable(T, fullFileName);
            
                    uialert(app.UIFigure, 'Data successfully saved as Excel file.', ...
                            'Success', 'Icon', 'success');
                end                 
        end

        % Value changed function: ExportAs_Plateau
        function ExportAs_PlateauValueChanged(app, event)
            selectedValue = app.ExportAs_Plateau.Value;
            if strcmp(selectedValue, 'Extend full page')
                newFig = figure('Name', 'Full Screen Plot - Plateau', 'NumberTitle', 'off');
                newAxes = axes('Parent', newFig);
                copyobj(app.AxesPlateauResponse.Children, newAxes);
                newFig.Position = get(0, 'ScreenSize');
                xlabel(newAxes, 'Time [sec]');
                ylabel(newAxes, 'Δ F / F [%]');
                title(newAxes, 'Plateau Response Signals');
        
            elseif strcmp(selectedValue, 'save as .xlsx')
                [file, path] = uiputfile('*.xlsx', 'Save Plateau Data As');
                if isequal(file, 0)
                    return;
                end
                fullFileName = fullfile(path, file);
        
                timeVec = app.TimeVec;
                signalMatrix = app.Plateau_sig;
        
                dataToExport = [timeVec(:), signalMatrix];
                varNames = ['Time_sec', strcat("ROI_", string(1:size(signalMatrix, 2)))];
                T = array2table(dataToExport, 'VariableNames', varNames);
                writetable(T, fullFileName);
        
                uialert(app.UIFigure, 'Data successfully saved as Excel file.', ...
                        'Success', 'Icon', 'success');
            end
        end

        % Value changed function: ExportAs_Undefined
        function ExportAs_UndefinedValueChanged(app, event)
            selectedValue = app.ExportAs_Undefined.Value;
            if strcmp(selectedValue, 'Extend full page')
                newFig = figure('Name', 'Full Screen Plot - Undefined', 'NumberTitle', 'off');
                newAxes = axes('Parent', newFig);
                copyobj(app.AxesUndefined.Children, newAxes);
                newFig.Position = get(0, 'ScreenSize');
                xlabel(newAxes, 'Time [sec]');
                ylabel(newAxes, 'Δ F / F [%]');
                title(newAxes, 'Undefined Signals');
        
            elseif strcmp(selectedValue, 'save as .xlsx')
                [file, path] = uiputfile('*.xlsx', 'Save Undefined Data As');
                if isequal(file, 0)
                    return;
                end
                fullFileName = fullfile(path, file);
        
                timeVec = app.TimeVec;
                signalMatrix = app.Undefined_sig;
        
                dataToExport = [timeVec(:), signalMatrix];
                varNames = ['Time_sec', strcat("ROI_", string(1:size(signalMatrix, 2)))];
                T = array2table(dataToExport, 'VariableNames', varNames);
                writetable(T, fullFileName);
        
                uialert(app.UIFigure, 'Data successfully saved as Excel file.', ...
                        'Success', 'Icon', 'success');
            end      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.landingScreen = uipanel(app.UIFigure);
            app.WelcometotheOPCCalciumAnalysisAPPLabel = uilabel(app.landingScreen);
            app.WelcometotheOPCCalciumAnalysisAPPLabel.Text = {'Welcome to the OPC Calcium Analysis App'; ''};
            app.Image_8 = uiimage(app.landingScreen);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'צילום_מסך_2024-07-06_132739-removebg-preview.png');
            app.SingleFileButton = uibutton(app.landingScreen, 'push');
            app.SingleFileButton.ButtonPushedFcn = createCallbackFcn(app, @SingleFileButtonPushed, true);
            app.SingleFileButton.Text = 'Single File';
            app.BatchAnalysisButton = uibutton(app.landingScreen, 'push');
            app.BatchAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BatchAnalysisButtonPushed, true);
            app.BatchAnalysisButton.Text = 'Batch Analysis';
            app.PleaseselectwhereyoudliketobeginLabel = uilabel(app.landingScreen);
            app.PleaseselectwhereyoudliketobeginLabel.Text = 'Please select where you’d like to begin';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.AxesUndefined = uiaxes(app.PreprocessingTab);
            title(app.AxesUndefined, 'Undifained Signals')
            xlabel(app.AxesUndefined, 'Time [sec]')
            ylabel(app.AxesUndefined, 'Δ F / F [%]')
            zlabel(app.AxesUndefined, 'Z')
            app.AxesUndefined.XLim = [0 1000];
            app.AxesUndefined.YLim = [-0.5 0.5];
            app.AxesUndefined.XTick = [0 500 1000];
            app.AxesUndefined.XTickLabel = {'0'; '500'; '1000'};
            app.AxesUndefined.YTick = [-0.5 0 0.5];
            app.AxesUndefined.YTickLabel = {'-2'; '0'; '2'};
            app.AxesUndefined.Visible = 'off';
            app.AxesPlateauResponse = uiaxes(app.PreprocessingTab);
            title(app.AxesPlateauResponse, 'Plateau Reaponse')
            xlabel(app.AxesPlateauResponse, 'Time [sec]')
            ylabel(app.AxesPlateauResponse, 'Δ F / F [%]')
            zlabel(app.AxesPlateauResponse, 'Z')
            app.AxesPlateauResponse.XLim = [0 1000];
            app.AxesPlateauResponse.YLim = [-0.5 0.5];
            app.AxesPlateauResponse.XTick = [0 500 1000];
            app.AxesPlateauResponse.XTickLabel = {'0'; '500'; '1000'};
            app.AxesPlateauResponse.YTick = [-0.5 0 0.5];
            app.AxesPlateauResponse.YTickLabel = {'-2'; '0'; '2'};
            app.AxesPlateauResponse.Visible = 'off';
            app.AxesLowActivity = uiaxes(app.PreprocessingTab);
            title(app.AxesLowActivity, 'Low activity')
            xlabel(app.AxesLowActivity, 'Time [sec]')
            ylabel(app.AxesLowActivity, 'Δ F / F [%]')
            zlabel(app.AxesLowActivity, 'Z')
            app.AxesLowActivity.XLim = [0 1000];
            app.AxesLowActivity.YLim = [-0.5 0.5];
            app.AxesLowActivity.XTick = [0 500 1000];
            app.AxesLowActivity.XTickLabel = {'0'; '500'; '1000'};
            app.AxesLowActivity.YTick = [-0.5 0 0.5];
            app.AxesLowActivity.YTickLabel = {'-2'; '0'; '2'};
            app.AxesLowActivity.Visible = 'off';
            app.AxesOscillatory = uiaxes(app.PreprocessingTab);
            title(app.AxesOscillatory, 'Oscillatory Signals')
            xlabel(app.AxesOscillatory, 'Time [sec]')
            ylabel(app.AxesOscillatory, 'Δ F / F [%]')
            zlabel(app.AxesOscillatory, 'Z')
            app.AxesOscillatory.XLim = [0 1000];
            app.AxesOscillatory.YLim = [-0.5 0.5];
            app.AxesOscillatory.XTick = [0 500 1000];
            app.AxesOscillatory.XTickLabel = {'0'; '500'; '1000'};
            app.AxesOscillatory.YTick = [-0.5 0 0.5];
            app.AxesOscillatory.YTickLabel = {'-2'; '0'; '2'};
            app.AxesOscillatory.Visible = 'off';
            app.Image = uiimage(app.PreprocessingTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'צילום_מסך_2024-07-06_132739-removebg-preview.png');
            app.DefaultiveCheckBox = uicheckbox(app.PreprocessingTab);
            app.DefaultiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultiveCheckBoxValueChanged, true);
            app.DefaultiveCheckBox.Visible = 'off';
            app.DefaultiveCheckBox.Text = ' Use Default Parameters (Uncheck to Modify)';
            app.DefaultiveCheckBox.Value = true;
            app.ParameterPanel = uipanel(app.PreprocessingTab);
            app.ParameterPanel.Title = '  Parameters';
            app.ParameterPanel.Visible = 'off';
            app.OligodendrocytesParametersLabel = uilabel(app.ParameterPanel);
            app.OligodendrocytesParametersLabel.Text = 'Oligodendrocytes Parameters';
            app.GeneralParametersLabel = uilabel(app.ParameterPanel);
            app.GeneralParametersLabel.Text = 'General Parameters';
            app.TimeToAnalyzeEditFieldLabel = uilabel(app.ParameterPanel);
            app.TimeToAnalyzeEditFieldLabel.Text = 'Time To Analyze';
            app.TimeToAnalyzeEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.TimeToAnalyzeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeToAnalyzeEditFieldValueChanged, true);
            app.TimeToAnalyzeEditField.Value = 120;
            app.TrimmingEditFieldLabel = uilabel(app.ParameterPanel);
            app.TrimmingEditFieldLabel.Text = 'Trimming';
            app.TrimmingEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.TrimmingEditField.ValueChangedFcn = createCallbackFcn(app, @TrimmingEditFieldValueChanged, true);
            app.TrimmingEditField.Value = 10;
            app.SmoothingFactorEditFieldLabel = uilabel(app.ParameterPanel);
            app.SmoothingFactorEditFieldLabel.Text = 'Smoothing Factor ';
            app.SmoothingFactorEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.SmoothingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingFactorEditFieldValueChanged, true);
            app.SmoothingFactorEditField.Value = 15;
            app.MinimalDurationsecLabel = uilabel(app.ParameterPanel);
            app.MinimalDurationsecLabel.Text = 'Minimal Duration (sec)';
            app.O_MinimalDuration = uispinner(app.ParameterPanel);
            app.O_MinimalDuration.ValueChangedFcn = createCallbackFcn(app, @O_MinimalDurationValueChanged, true);
            app.O_MinimalDuration.Value = 21;
            app.MaximalDurationsecSpinnerLabel = uilabel(app.ParameterPanel);
            app.MaximalDurationsecSpinnerLabel.Text = 'Maximal Duration (sec)';
            app.O_MaximalDuration = uispinner(app.ParameterPanel);
            app.O_MaximalDuration.ValueChangedFcn = createCallbackFcn(app, @O_MaximalDurationValueChanged, true);
            app.O_MaximalDuration.Value = 180;
            app.MinimalProminenceLabel = uilabel(app.ParameterPanel);
            app.MinimalProminenceLabel.Text = 'Minimal Prominence';
            app.O_MinimalProminence = uispinner(app.ParameterPanel);
            app.O_MinimalProminence.ValueChangedFcn = createCallbackFcn(app, @O_MinimalProminenceValueChanged, true);
            app.O_MinimalProminence.Value = 0.3;
            app.MinimalDistancesecSpinnerLabel = uilabel(app.ParameterPanel);
            app.MinimalDistancesecSpinnerLabel.Text = 'Minimal Distance (sec)';
            app.O_MinimalDistance = uispinner(app.ParameterPanel);
            app.O_MinimalDistance.ValueChangedFcn = createCallbackFcn(app, @O_MinimalDistanceValueChanged, true);
            app.O_MinimalDistance.Value = 60;
            app.ApplyChngesButton = uibutton(app.ParameterPanel, 'push');
            app.ApplyChngesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChngesButtonPushed, true);
            app.ApplyChngesButton.Text = 'Apply Chnges';
            app.CancelButton = uibutton(app.ParameterPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TimeToBaselineEditFieldLabel = uilabel(app.ParameterPanel);
            app.TimeToBaselineEditFieldLabel.Text = 'Time To Baseline';
            app.TimeToBaselineEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.TimeToBaselineEditField.ValueChangedFcn = createCallbackFcn(app, @TimeToBaselineEditFieldValueChanged, true);
            app.TimeToBaselineEditField.Value = 120;
            app.FrameRateEditFieldLabel = uilabel(app.ParameterPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.FrameRateEditField.Value = 1.08;
            app.MessageLabel_8 = uilabel(app.PreprocessingTab);
            app.MessageLabel_8.Text = '';
            app.Pleaseupload_Label = uilabel(app.PreprocessingTab);
            app.Pleaseupload_Label.Text = {'Please upload an Excel file (.xlsx)'; 'containing ROI calcium data.'; 'The file should include:'; '• One sheet per recording'; '• Time in the first column'; '• ROI signals in the following columns'};
            app.elipsis = uibutton(app.PreprocessingTab, 'push');
            app.elipsis.ButtonPushedFcn = createCallbackFcn(app, @elipsisButtonPushed, true);
            app.elipsis.Visible = 'off';
            app.elipsis.Text = '. . .';
            app.elipsis_Oscilatory = uibutton(app.PreprocessingTab, 'push');
            app.elipsis_Oscilatory.ButtonPushedFcn = createCallbackFcn(app, @elipsis_OscilatoryButtonPushed, true);
            app.elipsis_Oscilatory.Visible = 'off';
            app.elipsis_Oscilatory.Text = '. . .';
            app.ExportAs_Undefined = uilistbox(app.PreprocessingTab);
            app.ExportAs_Undefined.Items = {'Select an option...', 'Extend full page', 'save as .xlsx'};
            app.ExportAs_Undefined.ValueChangedFcn = createCallbackFcn(app, @ExportAs_UndefinedValueChanged, true);
            app.ExportAs_Undefined.Visible = 'off';
            app.ExportAs_Undefined.Value = 'Select an option...';
            app.ExportAs_Plateau = uilistbox(app.PreprocessingTab);
            app.ExportAs_Plateau.Items = {'Select an option...', 'Extend full page', 'save as .xlsx'};
            app.ExportAs_Plateau.ValueChangedFcn = createCallbackFcn(app, @ExportAs_PlateauValueChanged, true);
            app.ExportAs_Plateau.Visible = 'off';
            app.ExportAs_Plateau.Value = 'Select an option...';
            app.ExportAs_Low = uilistbox(app.PreprocessingTab);
            app.ExportAs_Low.Items = {'Select an option...', 'Extend full page', 'save as .xlsx'};
            app.ExportAs_Low.ValueChangedFcn = createCallbackFcn(app, @ExportAs_LowValueChanged, true);
            app.ExportAs_Low.Visible = 'off';
            app.ExportAs_Low.Value = 'Select an option...';
            app.ExportAs_Oscilatory = uilistbox(app.PreprocessingTab);
            app.ExportAs_Oscilatory.Items = {'Select an option...', 'Extend full page', 'save as .xlsx'};
            app.ExportAs_Oscilatory.ValueChangedFcn = createCallbackFcn(app, @ExportAs_OscilatoryValueChanged, true);
            app.ExportAs_Oscilatory.Visible = 'off';
            app.ExportAs_Oscilatory.Value = 'Select an option...';
            app.elipsis_Low = uibutton(app.PreprocessingTab, 'push');
            app.elipsis_Low.ButtonPushedFcn = createCallbackFcn(app, @elipsis_LowButtonPushed2, true);
            app.elipsis_Low.Visible = 'off';
            app.elipsis_Low.Text = '. . .';
            app.elipsis_Plateau = uibutton(app.PreprocessingTab, 'push');
            app.elipsis_Plateau.ButtonPushedFcn = createCallbackFcn(app, @elipsis_PlateauButtonPushed2, true);
            app.elipsis_Plateau.Visible = 'off';
            app.elipsis_Plateau.Text = '. . .';
            app.elipsis_Undefined = uibutton(app.PreprocessingTab, 'push');
            app.elipsis_Undefined.ButtonPushedFcn = createCallbackFcn(app, @elipsis_UndefinedButtonPushed2, true);
            app.elipsis_Undefined.Visible = 'off';
            app.elipsis_Undefined.Text = '. . .';
            app.Hereyouwillsee_Label = uilabel(app.PreprocessingTab);
            app.Hereyouwillsee_Label.Text = 'Here you will see a preview of your data';
            app.LoadYourFileButton = uibutton(app.PreprocessingTab, 'push');
            app.LoadYourFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadYourFileButtonPushed, true);
            app.LoadYourFileButton.Text = 'Load Your File';
            app.Startbyloading_Label = uilabel(app.PreprocessingTab);
            app.Startbyloading_Label.Text = 'Start by loading your file!';
            app.MessageLabel = uilabel(app.PreprocessingTab);
            app.MessageLabel.Text = '';
            app.ExportAs = uilistbox(app.PreprocessingTab);
            app.ExportAs.Items = {'Select an option...', 'Export as PDF', 'Export as MAT', 'Export as JPG'};
            app.ExportAs.ValueChangedFcn = createCallbackFcn(app, @ExportAsValueChanged, true);
            app.ExportAs.Visible = 'off';
            app.ExportAs.Value = 'Select an option...';
            app.FeatureExtractionTab = uitab(app.TabGroup);
            app.FeatureExtractionTab.Title = 'Feature Extraction';
            app.FeatureExtractionLabel = uilabel(app.FeatureExtractionTab);
            app.FeatureExtractionLabel.Text = 'Feature Extraction';
            app.conditionButtonGroup = uibuttongroup(app.FeatureExtractionTab);
            app.conditionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @conditionButtonGroupSelectionChanged, true);
            app.conditionButtonGroup.Title = 'condition';
            app.MutantButton = uiradiobutton(app.conditionButtonGroup);
            app.MutantButton.Text = 'Mutant';
            app.MutantButton.Value = true;
            app.ControlButton = uiradiobutton(app.conditionButtonGroup);
            app.ControlButton.Text = 'Control';
            app.SaveFeaturesButton = uibutton(app.FeatureExtractionTab, 'push');
            app.SaveFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFeaturesButtonPushed2, true);
            app.SaveFeaturesButton.Visible = 'off';
            app.SaveFeaturesButton.Text = 'Save Features';
            app.ChoosematfilesButton = uibutton(app.FeatureExtractionTab, 'push');
            app.ChoosematfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosematfilesButtonPushed2, true);
            app.ChoosematfilesButton.Text = 'Choose .mat files';
            app.Image_6 = uiimage(app.FeatureExtractionTab);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'צילום_מסך_2024-07-06_132739-removebg-preview.png');
            app.MessageLabel_2 = uilabel(app.FeatureExtractionTab);
            app.MessageLabel_2.Text = '';
            app.MessageLabel_3 = uilabel(app.FeatureExtractionTab);
            app.MessageLabel_3.Text = '';
            app.SanityCheckTab = uitab(app.TabGroup);
            app.SanityCheckTab.Title = 'Sanity Check';
            app.sanityAxes = uiaxes(app.SanityCheckTab);
            title(app.sanityAxes, 'Peaks of a singel ROI')
            xlabel(app.sanityAxes, 'X')
            ylabel(app.sanityAxes, 'Y')
            zlabel(app.sanityAxes, 'Z')
            app.sanityAxes.Visible = 'off';
            app.SanityCheckLabel = uilabel(app.SanityCheckTab);
            app.SanityCheckLabel.Text = 'Sanity Check';
            app.Image_7 = uiimage(app.SanityCheckTab);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'צילום_מסך_2024-07-06_132739-removebg-preview.png');
            app.ChoosematfileButton = uibutton(app.SanityCheckTab, 'push');
            app.ChoosematfileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosematfileButtonPushed, true);
            app.ChoosematfileButton.Text = 'Choose .mat file';
            app.SaveplotButton = uibutton(app.SanityCheckTab, 'push');
            app.SaveplotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotButtonPushed, true);
            app.SaveplotButton.Visible = 'off';
            app.SaveplotButton.Text = 'Save plot';
            app.ChooseCellNumberEditFieldLabel_2 = uilabel(app.SanityCheckTab);
            app.ChooseCellNumberEditFieldLabel_2.Text = 'Choose cell number';
            app.ChooseCellNumberEditField_2 = uieditfield(app.SanityCheckTab, 'numeric');
            app.ChooseCellNumberEditField_2.Value = 1;
            app.MessageLabel_6 = uilabel(app.SanityCheckTab);
            app.MessageLabel_6.Text = '';
            app.MessageLabel_7 = uilabel(app.SanityCheckTab);
            app.MessageLabel_7.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcium_Imaging_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end